classdef powerfactorimprovement < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LoadPowerkWEditFieldLabel       matlab.ui.control.Label
        LoadPowerEditField              matlab.ui.control.NumericEditField
        LoadVoltageVEditFieldLabel      matlab.ui.control.Label
        LoadVoltageEditField            matlab.ui.control.NumericEditField
        LoadPowerFactorInitialEditFieldLabel  matlab.ui.control.Label
        InitialPowerFactorEditField     matlab.ui.control.NumericEditField
        DesiredPowerFactorEditFieldLabel  matlab.ui.control.Label
        DesiredPowerFactorEditField     matlab.ui.control.NumericEditField
        ToleranceEditFieldLabel         matlab.ui.control.Label
        ToleranceEditField              matlab.ui.control.NumericEditField
        MaximumIterationsEditFieldLabel  matlab.ui.control.Label
        MaxIterationsEditField          matlab.ui.control.NumericEditField
        CalculateButton                 matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        ExitButton                      matlab.ui.control.Button
        RequiredReactivePowerkVAREditFieldLabel  matlab.ui.control.Label
        RequiredReactivePowerEditField  matlab.ui.control.NumericEditField
        OptimalCapacitanceFLabel        matlab.ui.control.Label
        OptimalCapacitanceEditField     matlab.ui.control.NumericEditField
        ConvergenceStatusLabel          matlab.ui.control.Label
        FrequencyEditFieldLabel         matlab.ui.control.Label
        FrequencyEditField              matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        EditField                       matlab.ui.control.EditField
        EditField_2                     matlab.ui.control.EditField
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        PowerfactorimprovementbycalculatingoptimalcapacitanceLabel  matlab.ui.control.Label
        IterationstakenEditFieldLabel   matlab.ui.control.Label
        IterationstakenEditField        matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
  % Ensure inputs are numeric scalars
    P_load = double(app.LoadPowerEditField.Value);
    V = double(app.LoadVoltageEditField.Value);
    pf_initial = double(app.InitialPowerFactorEditField.Value);
    pf_desired = double(app.DesiredPowerFactorEditField.Value);
    tol = double(app.ToleranceEditField.Value);
    max_iter = double(app.MaxIterationsEditField.Value);
    f=double(app.FrequencyEditField.Value);
    % Compute required reactive power
    Q_initial = P_load * tan(acos(pf_initial));
    Q_desired = P_load * tan(acos(pf_desired));
    Q_required = Q_initial - Q_desired;  % Required reactive power (kVAR)

    % Newton-Raphson method for optimal capacitance
    C = 1e-6;  % Initial guess (Farads)
    iter = 0;
    error = 1;

    while abs(error) > tol && iter < max_iter
    iter = iter + 1;
    
    % Function and derivative
    f_C = Q_required - (V^2 * C * 2 * pi * f);
    df_C = - (V^2 * 2 * pi * f);
    
    % Newton-Raphson update
    C_new = C - f_C / df_C;
    
    % Compute error
    error = abs(C_new - C);
    
    % Update capacitance value
    C = C_new;
    end

    % Ensure numeric outputs
    app.RequiredReactivePowerEditField.Value = double(Q_required);
    app.OptimalCapacitanceEditField.Value = double(C * 10^3);  % Convert to F
       app.IterationstakenEditField.Value = double(iter);

    % Fix: Ensure Convergence Status is text-based by using a Label
    if error < tol
        app.ConvergenceStatusLabel.Text = "Converged";  % Correct
    else
        app.ConvergenceStatusLabel.Text = "Not Converged";  % Correct
   
    end


        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
          app.LoadPowerEditField.Value = 0;
    app.LoadVoltageEditField.Value = 0;
    app.InitialPowerFactorEditField.Value = 0;
    app.DesiredPowerFactorEditField.Value = 0;
    app.ToleranceEditField.Value = 0;
    app.MaxIterationsEditField.Value = 0;
    app.RequiredReactivePowerEditField.Value = 0;
    app.OptimalCapacitanceEditField.Value = 0;
    app.IterationstakenEditField.Value= 0;
    app.ConvergenceStatusLabel.Text = "";  
    app.FrequencyEditField.Value= 0;
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LoadPowerkWEditFieldLabel = uilabel(app.UIFigure);
            app.LoadPowerkWEditFieldLabel.Text = 'Load Power(kW)';
            app.LoadPowerEditField = uieditfield(app.UIFigure, 'numeric');
            app.LoadVoltageVEditFieldLabel = uilabel(app.UIFigure);
            app.LoadVoltageVEditFieldLabel.Text = 'Load Voltage(V)';
            app.LoadVoltageEditField = uieditfield(app.UIFigure, 'numeric');
            app.LoadPowerFactorInitialEditFieldLabel = uilabel(app.UIFigure);
            app.LoadPowerFactorInitialEditFieldLabel.Text = 'Load Power Factor(Initial)';
            app.InitialPowerFactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.DesiredPowerFactorEditFieldLabel = uilabel(app.UIFigure);
            app.DesiredPowerFactorEditFieldLabel.Text = 'Desired Power Factor';
            app.DesiredPowerFactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.ToleranceEditFieldLabel = uilabel(app.UIFigure);
            app.ToleranceEditFieldLabel.Text = 'Tolerance ';
            app.ToleranceEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximumIterationsEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumIterationsEditFieldLabel.Text = 'Maximum Iterations';
            app.MaxIterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.RequiredReactivePowerkVAREditFieldLabel = uilabel(app.UIFigure);
            app.RequiredReactivePowerkVAREditFieldLabel.Text = 'Required Reactive Power (kVAR)';
            app.RequiredReactivePowerEditField = uieditfield(app.UIFigure, 'numeric');
            app.OptimalCapacitanceFLabel = uilabel(app.UIFigure);
            app.OptimalCapacitanceFLabel.Text = 'Optimal Capacitance (F)';
            app.OptimalCapacitanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConvergenceStatusLabel = uilabel(app.UIFigure);
            app.ConvergenceStatusLabel.Text = 'Convergence Status';
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.TextArea.Value = {'Input Segment:'};
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'Output Segment:';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.Editable = 'off';
            app.EditField_2.Value = 'Projected by Group-6(C2)';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.PowerfactorimprovementbycalculatingoptimalcapacitanceLabel = uilabel(app.UIFigure);
            app.PowerfactorimprovementbycalculatingoptimalcapacitanceLabel.Text = 'Power factor improvement by calculating optimal capacitance';
            app.IterationstakenEditFieldLabel = uilabel(app.UIFigure);
            app.IterationstakenEditFieldLabel.Text = 'Iterations taken';
            app.IterationstakenEditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = powerfactorimprovement

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end